classdef lic_new_alreadyasked < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ActivationUIFigure              matlab.ui.Figure
        YourIID                         matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        ExitButton                      matlab.ui.control.Button
        CopyButton                      matlab.ui.control.Button
        LicenseactivationrequiredLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [~,iid,~] = license_check; iid=char(iid);
            app.YourIID.Value = iid;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
            exit;
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            [~,iid,~] = license_check; iid=char(iid);
            clipboard("copy",iid);
            waitfor(msgbox('Your Installation ID has been copied to the clipboard!','Done!','help'));
        end

        % Close request function: ActivationUIFigure
        function ActivationUIFigureCloseRequest(app, event)
            delete(app);
            exit;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ActivationUIFigure = uifigure('Visible', 'off');
            app.ActivationUIFigure.Name = 'Activation';
            app.ActivationUIFigure.CloseRequestFcn = createCallbackFcn(app, @ActivationUIFigureCloseRequest, true);
            app.Image = uiimage(app.ActivationUIFigure);
            app.Image.ImageSource = 'UA_Big.png';
            app.LicenseactivationrequiredLabel = uilabel(app.ActivationUIFigure);
            app.LicenseactivationrequiredLabel.Text = 'License activation required!';
            app.CopyButton = uibutton(app.ActivationUIFigure, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.ExitButton = uibutton(app.ActivationUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Label = uilabel(app.ActivationUIFigure);
            app.Label.Text = 'You''ve already requested an activation for this software. If 1 day or more has passed from your request, please send to licensing@ungeracademy.com the following code.';
            app.YourIID = uieditfield(app.ActivationUIFigure, 'text');
            app.YourIID.Value = 'Your IID...';
            % Show the figure after all components are created
            app.ActivationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lic_new_alreadyasked

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ActivationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ActivationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ActivationUIFigure)
        end
    end
end