classdef lic_new_activate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LicenseactivationUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        HelpButton                 matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        LoginactivateButton        matlab.ui.control.Button
        EmailLabel                 matlab.ui.control.Label
        PasswordLabel              matlab.ui.control.Label
        PasswordField              wt.PasswordField
        EmailEditField             matlab.ui.control.EditField
        Image                      matlab.ui.control.Image
        ComposerOYTExtracontentactivationLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ssz = get(groot,'ScreenSize');
            ssz = ssz(3:4);
            app_pos = app.LicenseactivationUIFigure.Position;
            app_pos = app_pos(3:4);
            app.LicenseactivationUIFigure.Position = [(ssz(1)-app_pos(1))/2 (ssz(2)-app_pos(2))/2 app_pos(1) app_pos(2)];

%             [~,iid,~] = licensing_addon.lic_new_activate.f_lifetime_check; iid=char(iid);
%             try
%                 webread(['https://licensing.ungeracademy.com/lics_reqs_sandbox/' licensing_addon.f_cleanStr4FileName(iid)],weboptions('Timeout',15,'ContentType','text'));
%                 status = 1;
%             catch
%                 status = 0;
%             end
%             if status
%                 app.LoginactivateButton.Enable = 0;
%                 app.LoginactivateButton.Tooltip = 'You cannot send another request as your''s one is under approval';
%                 waitfor(msgbox(['Your activation is under approval: if a day has passed from your request please send your IID to licensing@ungeracademy.com. Your IID has been copied into clipboard.' newline 'If you cannot paste your Installation ID, you can find it into IID.txt file into the program folder.']));
% 
%                 fid = fopen('iid.txt','w+'); fprintf(fid,'%s',iid); fclose(fid);
%                 winopen('iid.txt');
% 
%                 clipboard("copy",iid);
%             end
        end

        % Button pushed function: LoginactivateButton
        function LoginactivateButtonPushed(app, event)
            webopts = weboptions('ContentType','text','Timeout',15);

            fig = uifigure;
            selection = uiconfirm(fig,'This procedure will disable any previous activation for this software... Are you sure to proceed?','Confirm','Icon','warning');
            close(fig);
            if strcmpi(selection,'ok')
                mail = app.EmailEditField.Value;
                if numel(regexpi(mail,'[a-z0-9_]+@[a-z0-9]+(\.[a-z0-9]+)+','match'))==1
                    wb = f_waitbar_text_left(waitbar(0,''));
                    waitbar(0,wb,'Authenticating on Unger Academy portal');

                    % login su learn
                    try
                        webread('https://learn.ungeracademy.com/wp-json/wp/v2/posts',weboptions('HeaderFields',...
                            {'Authorization',['Basic ' matlab.net.base64encode([char(app.EmailEditField.Value) ':' char(app.PasswordField.Value)])]},"Timeout",30));
                        authenticated = 1;
                    catch
                        authenticated = 0;
                        close(wb);
                        waitfor(msgbox('Invalid email or password. Please check your credentials and try again.'));
                    end

                    if authenticated
                        waitbar(0,wb,'Sending request data');
                        [~,iid,~] = licensing_addons_OYT.f_lifetime_check; iid=char(iid);
                        fn = licensing_addons_OYT.f_cleanStr4FileName(iid);
                        fid=fopen(fn,'w+'); fprintf(fid,'%s',sprintf([iid,'\n',char(licensing_addons_OYT.AES('!T6f&[J6ySGt6@#Y','SHA-512').encrypt(mail))])); fclose(fid);
                        dec = strsplit(licensing_addons_OYT.AES('5w"Ga*;$^VT9nq2_','SHA-512').decrypt(webread('https://licensing.ungeracademy.com/lics_utils/req_sett',webopts)),'|');
                        f=ftp(dec(1),dec(2),dec(3)); clear dec; f.mput(fn); f.close;
                        delete(fn);
                        waitbar(0,wb,'Waiting to process your request');
                        for zz=1:30
                            try
                                webread(['https://licensing.ungeracademy.com/lics_requests/' fn],webopts);
                                status = 1;
                                break;
                            catch
                                status = 0;
                            end

                            try
                                webread(['https://licensing.ungeracademy.com/lics_data/auto/' fn],webopts);
                                status = 1;
                                break;
                            catch
                                status = 0;
                            end

                            try
                                webread(['https://licensing.ungeracademy.com/lics_reqs_sandbox/' fn],webopts);
                                status = 1;
                                break;
                            catch
                                status = 0;
                            end
                            pause(0.5);
                        end
                        if status
                            %waiting for approval
                            appr_time = 120;
                            for zz=1:appr_time
                                waitbar(zz/appr_time,wb,['Activation in progress: ~' num2str(appr_time-zz,'%d') ' seconds left']);

                                if ~exist('processed','var') || processed==0
                                    try
                                        webread(['https://licensing.ungeracademy.com/lics_requests/' fn],webopts);
                                        processed = 0;
                                        pause(1);
                                    catch
                                        processed = 1;
                                    end
                                end

                                if processed
                                    try
                                        webread(['https://licensing.ungeracademy.com/lics_data/auto/' fn],webopts);
                                        status2 = 1;
                                        break;
                                    catch
                                        status2 = 0;
                                    end

                                    try
                                        webread(['https://licensing.ungeracademy.com/lics_reqs_sandbox/' fn],webopts);
                                        status2 = 2;
                                        break;
                                    catch
                                        status2 = 0;
                                    end

                                    if status2==0
                                        break;
                                    end
                                end
                            end

                            close(wb);

                            switch status2
                                case 0
                                    if processed==1 && exist('status2','var') && status2==0
                                        waitfor(errordlg('Your request has been denied. Please check your email for further details.','Activation denied!'));
                                    else
                                        waitfor(msgbox('At the moment the activation is requiring more time than expected. Please wait for the email confermation or contact Unger Academy staff after 24 hours.'));
                                    end
                                case 1
                                    waitfor(msgbox('Your license has been successfully activated!','Activation successfull!','help'));
                                case 2
                                    waitfor(msgbox('Your license has been put under approval (e.g. you may have activated the software too many times). Please wait for a moderation from Unger Academy staff.','Activation under approval','warn'));
                            end
                            delete(app);
                        else
                            close(wb);
                            waitfor(msgbox('An error has occured, please contact Unger Academy staff to fix the problem','Error','error'));
                        end
                    end
                else
                    waitfor(msgbox('Your email is invalid. Please type a valid email','Error','error'));
                end
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            fig = uifigure;
            selection = uiconfirm(fig,'You should use this procedure only if you cannot activate this software with the normal procedure or if you want to activate an extra license you''ve already. Are you sure to proceed?','Confirm','Icon','warning');
            close(fig);

            if strcmpi(selection,'ok')
                [~,iid,~] = licensing_addons_OYT.f_lifetime_check();
                fid = fopen('iid.txt','w+');
                fprintf(fid,'%s',iid); fclose(fid);
                clipboard("copy",iid);
                web('https://form.jotform.com/222627464662056','-browser');
                waitfor(msgbox(['Your IID has been copied into clipboard: please submit it using the form that will appear.' newline 'If you face problems with the form, please send the IID it to licensing@ungeracademy.com.' newline 'If you cannot paste your Installation ID, you can find it into IID.txt file into the program folder.']));
                winopen('iid.txt');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LicenseactivationUIFigure = uifigure('Visible', 'off');
            app.LicenseactivationUIFigure.Name = 'License activation';
            app.GridLayout = uigridlayout(app.LicenseactivationUIFigure);
            app.ComposerOYTExtracontentactivationLabel = uilabel(app.GridLayout);
            app.ComposerOYTExtracontentactivationLabel.Text = 'Composer - OYT Extra content activation';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'UA_Big.png';
            app.EmailEditField = uieditfield(app.GridLayout, 'text');
            app.PasswordField = wt.PasswordField(app.GridLayout);
            app.PasswordLabel = uilabel(app.GridLayout);
            app.PasswordLabel.Text = 'Password: ';
            app.EmailLabel = uilabel(app.GridLayout);
            app.EmailLabel.Text = 'Email: ';
            app.LoginactivateButton = uibutton(app.GridLayout, 'push');
            app.LoginactivateButton.ButtonPushedFcn = createCallbackFcn(app, @LoginactivateButtonPushed, true);
            app.LoginactivateButton.Text = 'Login & activate';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Please fill the boxes with the credentials you use to log in the Unger Academy portal.';
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Tooltip = {'Click here if you''re want to activate a second license or if you have a login problem.'};
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.LicenseactivationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lic_new_activate

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LicenseactivationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.LicenseactivationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LicenseactivationUIFigure)
        end
    end
end