classdef lic_new_neverasked < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ActivationUIFigure              matlab.ui.Figure
        IfyouretryingtoactivateasecondlicenseclickhelpbuttonLabel  matlab.ui.control.Label
        HelpButton                      matlab.ui.control.Button
        email_field                     matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        ExitButton                      matlab.ui.control.Button
        SendrequestButton               matlab.ui.control.Button
        LicenseactivationrequiredLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
            exit;
        end

        % Button pushed function: SendrequestButton
        function SendrequestButtonPushed(app, event)
            mail = app.email_field.Value;
            if numel(regexpi(mail,'[a-z0-9_]+@[a-z0-9]+(\.[a-z0-9]+)+','match'))==1
                [~,iid,~] = licensing.f_lifetime_check; iid=char(iid);
                fn = licensing.f_cleanStr4FileName(iid);
                fid=fopen(fn,'w+');
                fprintf(fid,'%s',sprintf([iid,'\n',char(licensing.AES('!T6f&[J6ySGt6@#Y','SHA-512').encrypt(mail))]));
                fclose(fid);
                dec = strsplit(licensing.AES('5w"Ga*;$^VT9nq2_','SHA-512').decrypt(webread('https://licensing.ungeracademy.com/lics_utils/req_sett',weboptions('ContentType','text'))),'|');
                f=ftp(dec(1),dec(2),dec(3));
                clear dec;
                f.mput(fn);
                delete(fn);
                for zz=1:10
                    try
                        req = webread(['https://licensing.ungeracademy.com/lics_requests/',fn]);
                        status = 1;
                    catch
                        req = 'N/A';
                        status = 0;
                    end
                    if ~status
                        break;
                    else
                        pause(0.5);
                    end
                end
                if status
                    waitfor(msgbox('Your license request has been sent correctly to Unger Academy staff. Please wait for your confirmation email!','Done','help'));
                    delete(app);
                    exit;
                else
                    waitfor(msgbox('An error has occured, please contact Unger Academy staff to fix the problem','Error','error'));
                end
            else
                waitfor(msgbox('Your email is invalid. Please type a valid email','Error','error'));
            end
        end

        % Close request function: ActivationUIFigure
        function ActivationUIFigureCloseRequest(app, event)
            delete(app);
            exit;
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            fig = uifigure;
            selection = uiconfirm(fig,'You should use this procedure only if you cannot activate this software with the normal procedure or if you want to activate an extra license you''ve already. Are you sure to proceed?','Confirm','Icon','warning');
            close(fig);

            if strcmpi(selection,'ok')
                [~,iid,~] = licensing.f_lifetime_check();
                fid = fopen('iid.txt','w+');
                fprintf(fid,'%s',iid);
                fclose(fid);

                clipboard("copy",iid);
                waitfor(msgbox(['Your IID has been copied into clipboard, please send it to licensing@ungeracademy.com.' newline 'If you cannot paste your Installation ID, you can find it into IID.txt file into the program folder.']));
                delete(app);
                exit;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ActivationUIFigure = uifigure('Visible', 'off');
            app.ActivationUIFigure.Name = 'Activation';
            app.ActivationUIFigure.CloseRequestFcn = createCallbackFcn(app, @ActivationUIFigureCloseRequest, true);
            app.Image = uiimage(app.ActivationUIFigure);
            app.Image.ImageSource = 'UA_Big.png';
            app.LicenseactivationrequiredLabel = uilabel(app.ActivationUIFigure);
            app.LicenseactivationrequiredLabel.Text = 'License activation required!';
            app.SendrequestButton = uibutton(app.ActivationUIFigure, 'push');
            app.SendrequestButton.ButtonPushedFcn = createCallbackFcn(app, @SendrequestButtonPushed, true);
            app.SendrequestButton.Text = 'Send request';
            app.ExitButton = uibutton(app.ActivationUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Label = uilabel(app.ActivationUIFigure);
            app.Label.Text = 'Please type in the following box the email address you use to login into Unger Academy portal. You''ll receive an email to confirm activation has been successful.';
            app.email_field = uieditfield(app.ActivationUIFigure, 'text');
            app.email_field.Value = 'Type your email here...';
            app.HelpButton = uibutton(app.ActivationUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Tooltip = {'Click help if you need to activate an extra license you''ve already or if you cannot activate the license withing 24 hours.'};
            app.HelpButton.Text = 'Help';
            app.IfyouretryingtoactivateasecondlicenseclickhelpbuttonLabel = uilabel(app.ActivationUIFigure);
            app.IfyouretryingtoactivateasecondlicenseclickhelpbuttonLabel.Text = 'If you''re trying to activate a second license, click "help" button';
            % Show the figure after all components are created
            app.ActivationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lic_new_neverasked

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ActivationUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.ActivationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ActivationUIFigure)
        end
    end
end