classdef CodeWriterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UngerAcademyComposerUIFigure    matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ComposerAITab                   matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        TriggerTab                      matlab.ui.container.Tab
        StrategysideButtonGroup         matlab.ui.container.ButtonGroup
        OnlyShortButton                 matlab.ui.control.RadioButton
        OnlyLongButton                  matlab.ui.control.RadioButton
        LongShortButton                 matlab.ui.control.RadioButton
        numberofhighslowsbarsSpinner    matlab.ui.control.Spinner
        numberofhighslowsbarsSpinnerLabel  matlab.ui.control.Label
        TriggerDropDown                 matlab.ui.control.DropDown
        TriggerDropDown_3Label          matlab.ui.control.Label
        NextButton                      matlab.ui.control.Button
        TypeofStrategyButtonGroup       matlab.ui.container.ButtonGroup
        BiasButton                      matlab.ui.control.RadioButton
        MeanrevertingButton             matlab.ui.control.RadioButton
        TrendfollowingButton            matlab.ui.control.RadioButton
        EntryTab_2                      matlab.ui.container.Tab
        pattern_lock                    matlab.ui.control.Image
        maxdailyentries                 matlab.ui.control.Spinner
        LimitdailyentriestoCheckBox     matlab.ui.control.CheckBox
        sma_type                        matlab.ui.control.DropDown
        FiltermovingaverageCheckBox     matlab.ui.control.CheckBox
        filteroscillatordivtype         matlab.ui.control.DropDown
        FilterbyoscillatordivergenceCheckBox  matlab.ui.control.CheckBox
        FilterondailyADXCheckBox        matlab.ui.control.CheckBox
        PatternsDropDown                matlab.ui.control.DropDown
        PatternsDropDownLabel           matlab.ui.control.Label
        myendpause                      matlab.ui.control.Spinner
        TradingendpausehhmmLabel        matlab.ui.control.Label
        mystartpause                    matlab.ui.control.Spinner
        TradingstartpausehhmmLabel      matlab.ui.control.Label
        myendtime                       matlab.ui.control.Spinner
        TradingendtimehhmmLabel         matlab.ui.control.Label
        mystarttime                     matlab.ui.control.Spinner
        TradingstarttimehhmmLabel       matlab.ui.control.Label
        SetatradingpauseCheckBox        matlab.ui.control.CheckBox
        PreviousButton                  matlab.ui.control.Button
        NextButton_2                    matlab.ui.control.Button
        ExitTab_2                       matlab.ui.container.Tab
        trail_dist_val                  matlab.ui.control.Spinner
        DistanceSpinnerLabel            matlab.ui.control.Label
        trail_trigg_val                 matlab.ui.control.NumericEditField
        TriggerEditFieldLabel           matlab.ui.control.Label
        TrailingstopCheckBox            matlab.ui.control.CheckBox
        TakeprofitCheckBox              matlab.ui.control.CheckBox
        tgt_val                         matlab.ui.control.NumericEditField
        BreakevenCheckBox               matlab.ui.control.CheckBox
        be_val                          matlab.ui.control.NumericEditField
        stp_value                       matlab.ui.control.NumericEditField
        StoplossCheckBox                matlab.ui.control.CheckBox
        DisablepositionreverseCheckBox  matlab.ui.control.CheckBox
        MaximumtradedurationButtonGroup  matlab.ui.container.ButtonGroup
        maxdaysintrade                  matlab.ui.control.Spinner
        NnumberofsessionButton          matlab.ui.control.RadioButton
        intradayButton                  matlab.ui.control.RadioButton
        NolimitButton                   matlab.ui.control.RadioButton
        FlattimeLabel                   matlab.ui.control.Label
        flatTime                        matlab.ui.control.Spinner
        PreviousButton_2                matlab.ui.control.Button
        NextButton_3                    matlab.ui.control.Button
        StrategyTab                     matlab.ui.container.Tab
        ExportfromDatePicker            matlab.ui.control.DatePicker
        IncludeTitanExportfromCheckBox  matlab.ui.control.CheckBox
        SessEnd                         matlab.ui.control.Spinner
        SessionendtimehhmmLabel         matlab.ui.control.Label
        SessStart                       matlab.ui.control.Spinner
        SessionstarttimehhmmLabel       matlab.ui.control.Label
        contractsEditField              matlab.ui.control.NumericEditField
        contractsEditFieldLabel         matlab.ui.control.Label
        PreviousButton_3                matlab.ui.control.Button
        NextButton_4                    matlab.ui.control.Button
        AITab                           matlab.ui.container.Tab
        AI_lock                         matlab.ui.control.Image
        GeneratecodeButton              matlab.ui.control.Button
        requestToAI                     matlab.ui.control.TextArea
        askToAI_onoff                   matlab.ui.control.CheckBox
        PreviousButton_4                matlab.ui.control.Button
        ExplorerTab                     matlab.ui.container.Tab
        GotothelessonLabel              matlab.ui.control.Label
        GetthecodeButton                matlab.ui.control.Button
        ENButton                        matlab.ui.control.Button
        ITButton                        matlab.ui.control.Button
        ScriptnameDropDown              matlab.ui.control.DropDown
        ScriptnameDropDownLabel         matlab.ui.control.Label
        OutputTab                       matlab.ui.container.Tab
        codeArea                        matlab.ui.control.TextArea
        SettingsTab                     matlab.ui.container.Tab
        FontsizeSpinner                 matlab.ui.control.Spinner
        FontsizeSpinnerLabel            matlab.ui.control.Label
        Activate_AI_capabilities_button  matlab.ui.control.Button
        AIcapabilitiesactivationstatusSwitch  matlab.ui.control.Switch
        AIcapabilitiesactivationstatusSwitchLabel  matlab.ui.control.Label
        Activate_OYT_Button             matlab.ui.control.Button
        OYTextracontentsactivationstatusSwitch  matlab.ui.control.Switch
        OYTextracontentsactivationstatusSwitchLabel  matlab.ui.control.Label
        ATFextracontentsactivationstatusSwitch  matlab.ui.control.Switch
        ATFextracontentsactivationstatusSwitchLabel  matlab.ui.control.Label
        Activate_Extra5engines_Button   matlab.ui.control.Button
        infoButton                      matlab.ui.control.Button
        output_contextmenu              matlab.ui.container.ContextMenu
        CopytoclipboardMenu             matlab.ui.container.Menu
        SaveasfileMenu                  matlab.ui.container.Menu
    end


    properties (Access = private)
        ATFExtraContentsActivated;
        AIContentsActivated;
        OYTContentsActivated;
    end

    methods (Access = private)

        function results = f_openLesson(app,enit)
            v = app.ScriptnameDropDown.Value;

            if strcmpi(v,'Unger Webinar DAX First Hour')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/webinar-dax-first-hour-4/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/webinar-dax-first-hour-3/';
                end
            elseif strcmpi(v,'https://learn.ungeracademy.com/lessons/webinar-overnight-fib-2/')
                if enit==1
                    results = 'none';
                else
                    results = 'https://learn.ungeracademy.com/lessons/webinar-overnight-fib-2/';
                end
            elseif strcmpi(v,'FXFader') || strcmpi(v,'WeeklyBump') || strcmpi(v,'WeeklyBump2')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/single-or-multi-market-strategies-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/strategie-mono-mercato-o-multi-mercato-2/';
                end
            elseif strcmpi(v,'identifying markets')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/identifying-markets-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/identificare-i-diversi-mercati-2/';
                end
            elseif strcmpi(v,'PtnBaseSA')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/patterns-volatility-and-directional-ii/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/i-pattern-volatilita-e-direzionali-ii/';
                end
            elseif strcmpi(v,'Develop') || strcmpi(v,'DevelopID')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/development-of-a-trend-following-strategy-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/sviluppo-di-una-strategia-trend-following-2/';
                end
            elseif strcmpi(v,'DevelopRev')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/development-of-a-counter-trend-strategy-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/sviluppo-di-una-strategia-counter-trend-2/';
                end
            elseif strcmpi(v,'DevelopBias')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/development-of-a-bias-strategy-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/sviluppo-di-una-strategia-bias-2/';
                end
            elseif strcmpi(v,'UATrendDeveloper')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/trend-developer-code-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/codice-trend-developer-2/';
                end
            elseif strcmpi(v,'UALevelFader')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/level-fader-code-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/codice-level-fader-2/';
                end
            elseif strcmpi(v,'UABollBandFader') || strcmpi(v,'UABIASExplorer')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/bollinger-bias-codes-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/codici-bollinger-e-bias-2/';
                end
            elseif strcmpi(v,'myGapFinder')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/bonus-working-on-gaps-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/bonus-lavorare-sui-gap-2/';
                end
            elseif strcmpi(v,'ProgStopDevelop')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/bonus-time-factor-and-progressive-stops-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/bonus-il-fattore-tempo-e-gli-stop-progressivi-2/';
                end
            elseif strcmpi(v,'IntraToOver')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/bonus-how-to-change-an-intraday-strategy-into-an-overnight-one-working-on-exits-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/bonus-come-trasformare-una-strategia-intraday-in-overnight-lavorando-sulle-uscite-2/';
                end
            elseif strcmpi(v,'ESCounter')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/in-depth-analysis-es-mini-sp-trading-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/approfondimento-trading-su-es-mini-sp-2/';
                end
            elseif strcmpi(v,'CLTrendDevelop')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/in-depth-analysis-cl-trading-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/approfondimento-trading-su-cl-2/';
                end
            elseif strcmpi(v,'_ohlcmulti5')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/managing-sessions-intro-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/gestione-sessioni-intro-2/';
                end
            elseif strcmpi(v,'PatternNeutralFast') || strcmpi(v,'PatternDirectionalFast') || strcmpi(v,'PatternFast')
                if enit==1
                    results = 'https://learn.ungeracademy.com/lessons/new-patterns-2/';
                else
                    results = 'https://learn.ungeracademy.com/lessons/pattern-nuovi/';
                end
            elseif strcmpi(v,'UA_DivergenceMACD_Spotter') || strcmpi(v,'UA_DivergenceRSI_Spotter') || strcmpi(v,'iADXOnArray')
                %                 if enit==1
                results = 'none';
                %                 else
                %                     results = 'none';
                %                 end
            elseif strcmpi(v,'_UA_Engine_Pullback') || strcmpi(v,'_UA_Engine_Swing_Point') || strcmpi(v,'_UA_Engine_Volatility_Regime') || strcmpi(v,'_UA_Jumper') || strcmpi(v,'_UA_Ranger')
                if enit==1
                    results = 'https://learn.ungeracademy.com/topic/codici-bonus-algotrader-fast/';
                else
                    results = 'https://learn.ungeracademy.com/topic/algotrader-fast-bonus-codes/';
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% OYT
            license_extra_oyt_ok = licensing_addons_OYT.f_lic_check;
            if license_extra_oyt_ok
                app.PatternsDropDown.Items = {'PtnBaseSA', 'PatternFast', 'PtnNeutralFast + PtnDirectionalFast'};
                app.pattern_lock.Enable = 'off';
                app.pattern_lock.Visible = 'off';
                app.OYTextracontentsactivationstatusSwitch.Value = 'On';
                app.Activate_OYT_Button.Visible = 'off';
                app.Activate_OYT_Button.Enable = 'off';
                app.OYTContentsActivated = true;
            else
                %% OYT capabilities
                app.pattern_lock.Enable = 'on';
                app.pattern_lock.Visible = 'on';
                app.PatternsDropDown.Items = {'PatternLite'};
                app.OYTextracontentsactivationstatusSwitch.Value = 'Off';
                app.Activate_OYT_Button.Visible = 'on';
                app.Activate_OYT_Button.Enable = 'on';
                app.OYTContentsActivated = false;

            end
            
            %% 5 engines
            license_extra_5engines_ok = licensing_addon.f_lic_check;

            if license_extra_5engines_ok
                app.ATFextracontentsactivationstatusSwitch.Value = 'On';
                app.ATFExtraContentsActivated = true;
                app.ScriptnameDropDown.Items = [app.ScriptnameDropDown.Items, '_UA_Engine_Pullback', '_UA_Engine_Swing_Point', '_UA_Engine_Volatility_Regime', '_UA_Jumper', '_UA_Ranger'];
                app.Activate_Extra5engines_Button.Enable = 'off';
                app.Activate_Extra5engines_Button.Visible = 'off';
            end
            
            %% AI capabilities
            license_extra_AI_ok = licensing_addons_AI.f_lic_check;

            if license_extra_AI_ok
                app.askToAI_onoff.Enable = 'on';
                app.AI_lock.Visible = 'off';
                app.AI_lock.Enable = 'off';

                app.AIcapabilitiesactivationstatusSwitch.Value = 'On';
                app.Activate_AI_capabilities_button.Visible = 'off';
                app.Activate_AI_capabilities_button.Enable = 'off';
                app.AIContentsActivated = true;
            else
                app.askToAI_onoff.Enable = 'off';
                app.AI_lock.Visible = 'on';
                app.AI_lock.Enable = 'on';            

                app.AIcapabilitiesactivationstatusSwitch.Value = 'Off';
                app.Activate_AI_capabilities_button.Visible = 'on';
                app.Activate_AI_capabilities_button.Enable = 'on';
                app.AIContentsActivated = false;                
            end

            %% refresh strategie
            app.TypeofStrategyButtonGroupSelectionChanged(app);
            app.TabGroupSelectionChanged(app);

            %% global text size
            fn = 'tsz.txt';
            if isfile(fn)
                newfontsize = textread(fn,'%d');
                if newfontsize~=12
                    x=findobj(app.UngerAcademyComposerUIFigure,'-property','FontSize');
                    set(x,{'FontSize'},num2cell(newfontsize));
                    app.FontsizeSpinner.Value = newfontsize;
                end
            end
        end

        % Button pushed function: Activate_Extra5engines_Button
        function Activate_Extra5engines_ButtonPushed(app, event)
            %             defLic = licensing_addon.f_lic_checkDefault;
            %             switch defLic
            %                 case 0
            %                     license_ok = licensing_addon.auxilium.f_check_license_auxilium || licensing_addon.f_license_ip_check || licensing_addon.f_lic_fromRootCheckLifetimeorNewReq;
            %                 case 1
            %                     license_ok = licensing_addon.auxilium.f_check_license_auxilium;
            %                 case 2
            %                     license_ok = licensing_addon.f_license_ip_check;
            %                 case 3
            license_ok = licensing_addon.f_lic_fromRootCheckLifetimeorNewReq;
            %             end

            if license_ok
                app.ATFExtraContentsActivated = true;
                app.ATFextracontentsactivationstatusSwitch.Value = 'On';
                app.ScriptnameDropDown.Items = [app.ScriptnameDropDown.Items, '_UA_Engine_Pullback', '_UA_Engine_Swing_Point', '_UA_Engine_Volatility_Regime', '_UA_Jumper', '_UA_Ranger'];
                app.Activate_Extra5engines_Button.Enable = 'off';
                app.Activate_Extra5engines_Button.Visible = 'off';
                % app.TriggerDropDown.Items = [app.TriggerDropDown.Items, 'Jumper', 'Ranger'];
            end
        end

        % Button pushed function: Activate_OYT_Button
        function Activate_OYT_ButtonPushed(app, event)
            license_extra_oyt_ok = licensing_addons_OYT.f_lic_fromRootCheckLifetimeorNewReq;

            if license_extra_oyt_ok
                app.PatternsDropDown.Items = {'PtnBaseSA', 'PatternFast', 'PtnNeutralFast + PtnDirectionalFast'};
                app.pattern_lock.Enable = 'off';
                app.pattern_lock.Visible = 'off';
                app.OYTextracontentsactivationstatusSwitch.Value = 'On';
                app.Activate_OYT_Button.Visible = 'off';
                app.Activate_OYT_Button.Enable = 'off';
                app.OYTContentsActivated = true;
            else
                %% OYT capabilities
                app.pattern_lock.Enable = 'on';
                app.pattern_lock.Visible = 'on';
                app.ScriptnameDropDown.Items = {'_ohlcMulti5','PatternLite'};
                app.OYTextracontentsactivationstatusSwitch.Value = 'Off';
                app.Activate_OYT_Button.Visible = 'on';
                app.Activate_OYT_Button.Enable = 'on';
                app.OYTContentsActivated = false;

            end
        end

        % Button pushed function: Activate_AI_capabilities_button
        function Activate_AI_capabilities_buttonButtonPushed(app, event)
            license_extra_AI_ok = licensing_addons_AI.f_lic_fromRootCheckLifetimeorNewReq;

            if license_extra_AI_ok
                app.askToAI_onoff.Enable = 'on';
                app.AI_lock.Visible = 'off';
                app.AI_lock.Enable = 'off';

                app.AIcapabilitiesactivationstatusSwitch.Value = 'On';
                app.Activate_AI_capabilities_button.Visible = 'off';
                app.Activate_AI_capabilities_button.Enable = 'off';
                app.AIContentsActivated = true;
            else
                app.askToAI_onoff.Enable = 'off';
                app.AI_lock.Visible = 'on';
                app.AI_lock.Enable = 'on';            

                app.AIcapabilitiesactivationstatusSwitch.Value = 'Off';
                app.Activate_AI_capabilities_button.Visible = 'on';
                app.Activate_AI_capabilities_button.Enable = 'on';
                app.AIContentsActivated = false;                
            end            
        end

        % Callback function
        function SavetotextfileButtonPushed(app, event)
            %             exx = app.codeArea.Value;
            %             save('test.mat','exx');
            ff = ['Myscript' datestr(now,'yyyy-mm-dd HHMM') '.txt'];
            [file,name,path] = uiputfile('*.txt','Save script...',ff);
            if path>0
                fid = fopen([name file],'w+');
                %aa = char(app.codeArea.Value);
                aa2 = app.codeArea.Value;
                aa3 = [aa2{1} newline aa2{2} newline aa2{3}];
                fprintf(fid,'%s',aa3);
                fclose(fid);
                %save('test2.mat','aa2');
                waitfor(msgbox('Your script has been saved successfully!','Done!','modal'));
            end
        end

        % Callback function
        function exampleButtonPushed(app, event)
            f = figure('units','normalized','outerposition',[.25 .25 .5 .5]);
            title('Trigger example');
            chrt = imread('extras\highs1lows1rev.jpg');
            imshow(chrt,'Border','tight');
            hold on;
            disableDefaultInteractivity(gca);
            f.ToolBar = 'no';
            f.MenuBar = 'no';
        end

        % Button pushed function: GetthecodeButton
        function GetthecodeButtonPushed(app, event)
            app.codeArea.Value = webread(['https://learn.ungeracademy.com/documents/CodeWriter/' app.ScriptnameDropDown.Value '.txt']);
            waitfor(msgbox({'The script has been printed out successfully!';'Please check out the output tab!'},'Done!','modal'));
        end

        % Callback function
        function DownloadButtonPushed(app, event)
            [file,name,path] = uiputfile('*.txt','Save script...',[app.ScriptnameDropDown.Value '.txt']);
            if path>0
                websave([name file], ['https://learn.ungeracademy.com/documents/CodeWriter/' app.ScriptnameDropDown.Value '.txt']);
                waitfor(msgbox('The script has been saved to file successfully!','Done!','modal'));
            end
        end

        % Button pushed function: ENButton
        function ENButtonPushed(app, event)
            url = f_openLesson(app,1);
            if startsWith(url,'http')
                web(url);
            else
                waitfor(msgbox('There is no lesson available on this script, but you can download the script anyway!'));
            end
        end

        % Button pushed function: ITButton
        function ITButtonPushed(app, event)
            url = f_openLesson(app,2);
            if startsWith(url,'http')
                web(url);
            else
                waitfor(msgbox('There is no lesson available on this script, but you can download the script anyway!'));
            end
        end

        % Menu selected function: CopytoclipboardMenu
        function CopytoclipboardMenuSelected(app, event)
            clipboard('copy',strjoin(app.codeArea.Value,'\n'));
        end

        % Menu selected function: SaveasfileMenu
        function SaveasfileMenuSelected(app, event)
            [file,name,path] = uiputfile('*.txt','Save script...',['MyScript-' datestr(now,'yyyy-mm-dd HHMM') '.txt']);
            if path>0
                fid = fopen([name file],'w+');
                fprintf(fid,'%s',strjoin(app.codeArea.Value,'\n'));
                fclose(fid);
                if exist([name file],'file')
                    waitfor(msgbox('Your script has been saved successfully!'));
                end
            end
        end

        % Callback function
        function httpslearnungeracademycomButtonPushed(app, event)
            web('https://learn.ungeracademy.com','-browser');
        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
            [v, dd] = t_ver;
            waitfor(msgbox({'Composer';['Version ' num2str(v) ' - ' dd];'© 2021 Unger Academy'}))
        end

        % Selection changed function: TypeofStrategyButtonGroup
        function TypeofStrategyButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeofStrategyButtonGroup.SelectedObject;

            if strcmpi(selectedButton.Text,'Trend following')
                app.TriggerDropDown.Items = {'Trendfollower breakout on N sessions ago high/low',...
                    'Trendfollowing highest high/lowest low',...
                    'Trendfollower breakout highest/lowest session extremes values',...
                    'Simple moving average cross'};
                if app.ATFExtraContentsActivated
                    app.TriggerDropDown.Items = [app.TriggerDropDown.Items, 'Jumper'];
                end
            elseif strcmpi(selectedButton.Text,'Mean reverting')
                app.TriggerDropDown.Items = {'Bollinger reversal',...
                    'Reversal fade breakout',...
                    'Reversal with limit order N sessions ago extremes levels',...
                    'Reversal with limit orders on highest/lowest N sessions high/low'};
                if app.ATFExtraContentsActivated
                    app.TriggerDropDown.Items = [app.TriggerDropDown.Items, 'Ranger'];
                end
            elseif strcmpi(selectedButton.Text,'Bias')
                app.TriggerDropDown.Items = {'Bias with market orders',...
                    'Bias reversal with limit orders',...
                    'Bias breakout with stop orders'};
            end

            app.TriggerDropDownValueChanged(app);
        end

        % Value changed function: TriggerDropDown
        function TriggerDropDownValueChanged(app, event)
            v = app.TriggerDropDown.Value;
            if strcmpi(v,'Trendfollower breakout on N sessions ago high/low') || strcmpi(v,'Trendfollowing highest high/lowest low') ||...
                    strcmpi(v,'Trendfollower breakout highest/lowest session extremes values') || strcmpi(v,'Reversal with limit order N sessions ago extremes levels') ||...
                    strcmpi(v,'Reversal with limit orders on highest/lowest N sessions high/low')
                app.numberofhighslowsbarsSpinner.Visible = 'on';
                app.numberofhighslowsbarsSpinnerLabel.Visible = 'on';
            else
                app.numberofhighslowsbarsSpinner.Visible = 'off';
                app.numberofhighslowsbarsSpinnerLabel.Visible = 'off';
            end
        end

        % Selection change function: TabGroup3
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup3.SelectedTab;
            AselectedTab = app.TabGroup3.SelectedTab;
            % gestione colori
            confronto = AselectedTab.Tag;
            allC = app.TabGroup3.Children;
            curr_tab = false;
            for k = 1:length(allC)
                allC(k).ForegroundColor = "r"; %colore tab "future"
                if strcmp(allC(k).Tag,confronto)
                    curr_tab = true;
                end
                if curr_tab==false
                    allC(k).ForegroundColor = [2,142,42]/255;%"g"; %colore tab "passate"
                end
            end
            AselectedTab.ForegroundColor = "k"; %colore tab "attuale"
        end

        % Button pushed function: PreviousButton
        function NB_GoTo_TriggerTab(app, event)
            app.TabGroup3.SelectedTab = app.TriggerTab;
            app.TabGroupSelectionChanged(app)
        end

        % Button pushed function: NextButton, PreviousButton_2
        function NB_GoTo_EntryTab(app, event)
            app.TabGroup3.SelectedTab = app.EntryTab_2;
            app.TabGroupSelectionChanged(app)
        end

        % Button pushed function: NextButton_2, PreviousButton_3
        function NB_GoTo_ExitTab(app, event)
            app.TabGroup3.SelectedTab = app.ExitTab_2;
            app.TabGroupSelectionChanged(app)
            app.MaximumtradedurationButtonGroupSelectionChanged(app);
        end

        % Selection changed function: MaximumtradedurationButtonGroup
        function MaximumtradedurationButtonGroupSelectionChanged(app, event)
            selectedButton = app.MaximumtradedurationButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text,'No limit')
                app.FlattimeLabel.Visible = 'off';
                app.flatTime.Visible = 'off';
            else
                app.FlattimeLabel.Visible = 'on';
                app.flatTime.Visible = 'on';
            end
        end

        % Button pushed function: NextButton_3, PreviousButton_4
        function NB_GoTo_StrategyTab(app, event)
            app.TabGroup3.SelectedTab = app.StrategyTab;
            app.TabGroupSelectionChanged(app)
        end

        % Button pushed function: NextButton_4
        function NB_GoTo_AITab(app, event)
            app.TabGroup3.SelectedTab = app.AITab;
            app.TabGroupSelectionChanged(app)
        end

        % Button down function: ExitTab_2
        function ExitTab_2ButtonDown(app, event)
            app.MaximumtradedurationButtonGroupSelectionChanged(app);
        end

        % Value changed function: askToAI_onoff
        function askToAI_onoffValueChanged(app, event)
            value = app.askToAI_onoff.Value;
            if value
                app.requestToAI.Visible = 'on';
            else
                app.requestToAI.Visible = 'off';
            end
        end

        % Button pushed function: GeneratecodeButton
        function GeneratecodeButtonPushed(app, event)
            %% basic settings
            mycontracts = app.contractsEditField.Value;
            v = app.StrategysideButtonGroup.SelectedObject;
            v = v.Text;
            if strcmpi(v,'long & short')
                strat_side = 1;
            elseif strcmpi(v,'only long')
                strat_side = 2;
            elseif strcmpi(v,'only short')
                strat_side = 3;
            end
            noReverse = app.DisablepositionreverseCheckBox.Value;
            titan_start_date  = app.ExportfromDatePicker.Value;
            titanExport = app.IncludeTitanExportfromCheckBox.Value;

            %% Michael
            sess_start = app.SessStart.Value;
            sess_end = app.SessEnd.Value;

            %% trigger
            v = app.TriggerDropDown.Value;
            if strcmpi(v,'Trendfollower breakout on N sessions ago high/low')
                trigg_no = 1;
            elseif strcmpi(v,'Trendfollowing highest high/lowest low')
                trigg_no = 2;
            elseif strcmpi(v,'Trendfollower breakout highest/lowest session extremes values')
                trigg_no = 3;
            elseif strcmpi(v,'Bollinger reversal')
                trigg_no = 4;
            elseif strcmpi(v,'Reversal fade breakout')
                trigg_no = 5;
            elseif strcmpi(v,'Reversal with limit order N sessions ago extremes levels')
                trigg_no = 6;
            elseif strcmpi(v,'Reversal with limit orders on highest/lowest N sessions high/low')
                trigg_no = 7;
            elseif strcmpi(v,'Bias with market orders')
                trigg_no = 8;
            elseif strcmpi(v,'Bias breakout with stop orders')
                trigg_no = 9;
            elseif strcmpi(v,'Bias reversal with limit orders')
                trigg_no = 10;
            elseif strcmpi(v,'Simple moving average cross')
                trigg_no = 11;
            elseif strcmpi(v,'Jumper')
                trigg_no = 12;
            elseif strcmpi(v,'Ranger')
                trigg_no = 13;
            end
            trigg_hhll = app.numberofhighslowsbarsSpinner.Value;

            %% tw
            tw_start = app.mystarttime.Value;
            tw_end = app.myendtime.Value;
            tw_pauseOn = app.SetatradingpauseCheckBox.Value;
            tw_pause_start = app.mystartpause.Value;
            tw_pause_end = app.myendpause.Value;

            %% pattern
            v = app.PatternsDropDown.Value;
            if strcmpi(v,'PtnBaseSA')
                ptn_type = 1;
            elseif strcmpi(v,'PatternFast')
                ptn_type = 2;
            elseif strcmpi(v,'PtnNeutralFast + PtnDirectionalFast')
                ptn_type = 3;
            elseif strcmpi(v,'PatternLite')
                ptn_type = 4;
            end

            %% filters
            filt_sma_on = app.FiltermovingaverageCheckBox.Value;
            if strcmpi(app.sma_type.Value,'Simple') %1 = simple 2 = exponential
                filt_sma_type = 1;
            else
                filt_sma_type = 2;
            end

            filt_ADX_on = app.FilterondailyADXCheckBox.Value;
            filt_Div_on = app.FilterbyoscillatordivergenceCheckBox.Value;

            if strcmpi(app.filteroscillatordivtype.Value,'RSI') %1 = RSI 2 = MACD
                filt_Div_type = 1;
            else
                filt_Div_type = 2;
            end
            filt_max_daily_entries_on = app.LimitdailyentriestoCheckBox.Value;
            filt_max_daily_entries = app.maxdailyentries.Value;

            %% exits tweaks
            v = app.MaximumtradedurationButtonGroup.SelectedObject;
            v = v.Text;
            if strcmpi(v,'No limit')
                max_trade_duration_type = 1;
            elseif strcmpi(v,'intraday')
                max_trade_duration_type = 2;
            elseif strcmpi(v,'N number of session')
                max_trade_duration_type = 3;
            end

            max_trade3_sessions = app.maxdaysintrade.Value;
            flat_time = app.flatTime.Value;
            if app.StoplossCheckBox.Value
                stp = app.stp_value.Value;
            else
                stp = 0;
            end
            if app.BreakevenCheckBox.Value
                be = app.be_val.Value;
            else
                be = 0;
            end
            if app.TakeprofitCheckBox.Value
                tgt = app.tgt_val.Value;
            else
                tgt = 0;
            end
            if app.TrailingstopCheckBox.Value
                trail_onoff = 1;
            else
                trail_onoff = 0;
            end
            trail_trigg = app.trail_trigg_val.Value;
            trail_dist = app.trail_dist_val.Value;

            askToWarren = app.askToAI_onoff.Value;
            request_to_ai = strrep(strjoin(app.requestToAI.Value,'\n'),newline,'\n');

            writer_main;

            app.codeArea.Value = c;
            waitfor(msgbox({'The script has been printed out successfully!';'Please check out the output tab!'},'Done!','modal'));
            app.TabGroup.SelectedTab = app.OutputTab;
        end

        % Button down function: ComposerAITab
        function ComposerAITabButtonDown(app, event)
            app.TabGroupSelectionChanged(app);
        end

        % Value changed function: FontsizeSpinner
        function FontsizeSpinnerValueChanged(app, event)
            newfontsize = app.FontsizeSpinner.Value;
            x=findobj(app.UngerAcademyComposerUIFigure,'-property','FontSize');
            %xfs = cell2mat(get(x,'FontSize'));
            set(x,{'FontSize'},num2cell(newfontsize));
            fn = 'tsz.txt'; 
            fid = fopen('tsz.txt','w');
            fprintf(fid,'%d',newfontsize);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UngerAcademyComposerUIFigure = uifigure('Visible', 'off');
            app.UngerAcademyComposerUIFigure.Name = 'Unger Academy Composer';
            app.TabGroup = uitabgroup(app.UngerAcademyComposerUIFigure);
            app.ComposerAITab = uitab(app.TabGroup);
            app.ComposerAITab.Title = 'ComposerAI';
            app.ComposerAITab.ButtonDownFcn = createCallbackFcn(app, @ComposerAITabButtonDown, true);
            app.TabGroup3 = uitabgroup(app.ComposerAITab);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TriggerTab = uitab(app.TabGroup3);
            app.TriggerTab.Title = 'Trigger';
            app.TriggerTab.Tag = 'Trigger';
            app.TypeofStrategyButtonGroup = uibuttongroup(app.TriggerTab);
            app.TypeofStrategyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofStrategyButtonGroupSelectionChanged, true);
            app.TypeofStrategyButtonGroup.Title = 'Type of Strategy';
            app.TrendfollowingButton = uiradiobutton(app.TypeofStrategyButtonGroup);
            app.TrendfollowingButton.Text = 'Trend following';
            app.TrendfollowingButton.Value = true;
            app.MeanrevertingButton = uiradiobutton(app.TypeofStrategyButtonGroup);
            app.MeanrevertingButton.Text = 'Mean reverting';
            app.BiasButton = uiradiobutton(app.TypeofStrategyButtonGroup);
            app.BiasButton.Text = 'Bias';
            app.NextButton = uibutton(app.TriggerTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NB_GoTo_EntryTab, true);
            app.NextButton.Icon = fullfile(pathToMLAPP, 'icons', 'next.png');
            app.NextButton.Text = 'Next';
            app.TriggerDropDown_3Label = uilabel(app.TriggerTab);
            app.TriggerDropDown_3Label.Text = 'Trigger';
            app.TriggerDropDown = uidropdown(app.TriggerTab);
            app.TriggerDropDown.Items = {'Trendfollower breakout on N sessions ago high/low', 'Trendfollowing highest high/lowest low', 'Trendfollower breakout highest/lowest session extremes values', 'Bollinger reversal', 'Reversal fade breakout', 'Reversal with limit order N sessions ago extremes levels', 'Reversal with limit orders on highest/lowest N sessions high/low', 'Bias with market orders', 'Bias reversal with limit orders', 'Bias breakout with stop orders', 'Simple moving average cross'};
            app.TriggerDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerDropDownValueChanged, true);
            app.TriggerDropDown.Value = 'Trendfollower breakout on N sessions ago high/low';
            app.numberofhighslowsbarsSpinnerLabel = uilabel(app.TriggerTab);
            app.numberofhighslowsbarsSpinnerLabel.Text = 'number of highs/lows/bars';
            app.numberofhighslowsbarsSpinner = uispinner(app.TriggerTab);
            app.numberofhighslowsbarsSpinner.Value = 1;
            app.StrategysideButtonGroup = uibuttongroup(app.TriggerTab);
            app.StrategysideButtonGroup.Title = 'Strategy side';
            app.LongShortButton = uiradiobutton(app.StrategysideButtonGroup);
            app.LongShortButton.Text = 'Long & Short';
            app.LongShortButton.Value = true;
            app.OnlyLongButton = uiradiobutton(app.StrategysideButtonGroup);
            app.OnlyLongButton.Text = 'Only Long';
            app.OnlyShortButton = uiradiobutton(app.StrategysideButtonGroup);
            app.OnlyShortButton.Text = 'Only Short';
            app.EntryTab_2 = uitab(app.TabGroup3);
            app.EntryTab_2.Title = 'Entry';
            app.EntryTab_2.Tag = 'EntrySettings';
            app.NextButton_2 = uibutton(app.EntryTab_2, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NB_GoTo_ExitTab, true);
            app.NextButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'next.png');
            app.NextButton_2.Text = 'Next';
            app.PreviousButton = uibutton(app.EntryTab_2, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @NB_GoTo_TriggerTab, true);
            app.PreviousButton.Icon = fullfile(pathToMLAPP, 'icons', 'previous.png');
            app.PreviousButton.Text = 'Previous';
            app.SetatradingpauseCheckBox = uicheckbox(app.EntryTab_2);
            app.SetatradingpauseCheckBox.Text = 'Set a trading pause';
            app.TradingstarttimehhmmLabel = uilabel(app.EntryTab_2);
            app.TradingstarttimehhmmLabel.Text = 'Trading start time (hhmm)';
            app.mystarttime = uispinner(app.EntryTab_2);
            app.mystarttime.Step = 100;
            app.mystarttime.Limits = [0 2359];
            app.mystarttime.ValueDisplayFormat = '%.0f';
            app.mystarttime.Value = 1730;
            app.TradingendtimehhmmLabel = uilabel(app.EntryTab_2);
            app.TradingendtimehhmmLabel.Text = 'Trading end time (hhmm)';
            app.myendtime = uispinner(app.EntryTab_2);
            app.myendtime.Step = 100;
            app.myendtime.Limits = [0 2359];
            app.myendtime.ValueDisplayFormat = '%.0f';
            app.myendtime.Value = 1530;
            app.TradingstartpausehhmmLabel = uilabel(app.EntryTab_2);
            app.TradingstartpausehhmmLabel.Text = 'Trading start pause (hhmm)';
            app.mystartpause = uispinner(app.EntryTab_2);
            app.mystartpause.Step = 100;
            app.mystartpause.Limits = [0 2359];
            app.mystartpause.ValueDisplayFormat = '%.0f';
            app.mystartpause.Value = 100;
            app.TradingendpausehhmmLabel = uilabel(app.EntryTab_2);
            app.TradingendpausehhmmLabel.Text = 'Trading end pause (hhmm)';
            app.myendpause = uispinner(app.EntryTab_2);
            app.myendpause.Step = 100;
            app.myendpause.Limits = [0 2359];
            app.myendpause.ValueDisplayFormat = '%.0f';
            app.myendpause.Value = 800;
            app.PatternsDropDownLabel = uilabel(app.EntryTab_2);
            app.PatternsDropDownLabel.Text = 'Patterns';
            app.PatternsDropDown = uidropdown(app.EntryTab_2);
            app.PatternsDropDown.Items = {'PtnBaseSA', 'PatternFast', 'PtnNeutralFast + PtnDirectionalFast'};
            app.PatternsDropDown.Value = 'PtnBaseSA';
            app.FilterondailyADXCheckBox = uicheckbox(app.EntryTab_2);
            app.FilterondailyADXCheckBox.Text = 'Filter on daily ADX';
            app.FilterbyoscillatordivergenceCheckBox = uicheckbox(app.EntryTab_2);
            app.FilterbyoscillatordivergenceCheckBox.Text = 'Filter by oscillator divergence';
            app.filteroscillatordivtype = uidropdown(app.EntryTab_2);
            app.filteroscillatordivtype.Items = {'RSI', 'MACD'};
            app.filteroscillatordivtype.Value = 'RSI';
            app.FiltermovingaverageCheckBox = uicheckbox(app.EntryTab_2);
            app.FiltermovingaverageCheckBox.Text = 'Filter moving average';
            app.sma_type = uidropdown(app.EntryTab_2);
            app.sma_type.Items = {'Simple', 'Exponential'};
            app.sma_type.Value = 'Simple';
            app.LimitdailyentriestoCheckBox = uicheckbox(app.EntryTab_2);
            app.LimitdailyentriestoCheckBox.Text = 'Limit daily entries to ';
            app.LimitdailyentriestoCheckBox.Value = true;
            app.maxdailyentries = uispinner(app.EntryTab_2);
            app.maxdailyentries.Value = 2;
            app.pattern_lock = uiimage(app.EntryTab_2);
            app.pattern_lock.Enable = 'off';
            app.pattern_lock.Visible = 'off';
            app.pattern_lock.Tooltip = {'Please contact support to unlock this feature'};
            app.pattern_lock.ImageSource = fullfile(pathToMLAPP, 'icons', 'lock.png');
            app.ExitTab_2 = uitab(app.TabGroup3);
            app.ExitTab_2.Title = 'Exit';
            app.ExitTab_2.ButtonDownFcn = createCallbackFcn(app, @ExitTab_2ButtonDown, true);
            app.ExitTab_2.Tag = 'ExitSettings';
            app.NextButton_3 = uibutton(app.ExitTab_2, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NB_GoTo_StrategyTab, true);
            app.NextButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'next.png');
            app.NextButton_3.Text = 'Next';
            app.PreviousButton_2 = uibutton(app.ExitTab_2, 'push');
            app.PreviousButton_2.ButtonPushedFcn = createCallbackFcn(app, @NB_GoTo_EntryTab, true);
            app.PreviousButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'previous.png');
            app.PreviousButton_2.Text = 'Previous';
            app.flatTime = uispinner(app.ExitTab_2);
            app.flatTime.Step = 100;
            app.flatTime.Limits = [0 2359];
            app.flatTime.ValueDisplayFormat = '%.0f';
            app.flatTime.Value = 1530;
            app.FlattimeLabel = uilabel(app.ExitTab_2);
            app.FlattimeLabel.Text = 'Flat time';
            app.MaximumtradedurationButtonGroup = uibuttongroup(app.ExitTab_2);
            app.MaximumtradedurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MaximumtradedurationButtonGroupSelectionChanged, true);
            app.MaximumtradedurationButtonGroup.Title = 'Maximum trade duration';
            app.NolimitButton = uiradiobutton(app.MaximumtradedurationButtonGroup);
            app.NolimitButton.Text = 'No limit';
            app.NolimitButton.Value = true;
            app.intradayButton = uiradiobutton(app.MaximumtradedurationButtonGroup);
            app.intradayButton.Text = 'intraday';
            app.NnumberofsessionButton = uiradiobutton(app.MaximumtradedurationButtonGroup);
            app.NnumberofsessionButton.Text = 'N number of session';
            app.maxdaysintrade = uispinner(app.MaximumtradedurationButtonGroup);
            app.maxdaysintrade.Limits = [0 Inf];
            app.maxdaysintrade.Value = 2;
            app.DisablepositionreverseCheckBox = uicheckbox(app.ExitTab_2);
            app.DisablepositionreverseCheckBox.Text = 'Disable position reverse';
            app.StoplossCheckBox = uicheckbox(app.ExitTab_2);
            app.StoplossCheckBox.Text = 'Stop loss';
            app.StoplossCheckBox.Value = true;
            app.stp_value = uieditfield(app.ExitTab_2, 'numeric');
            app.stp_value.Limits = [0 Inf];
            app.stp_value.ValueDisplayFormat = '%.0f$';
            app.stp_value.Value = 1000;
            app.be_val = uieditfield(app.ExitTab_2, 'numeric');
            app.be_val.Limits = [0 Inf];
            app.be_val.ValueDisplayFormat = '%.0f$';
            app.be_val.Value = 1000;
            app.BreakevenCheckBox = uicheckbox(app.ExitTab_2);
            app.BreakevenCheckBox.Text = 'Break even';
            app.BreakevenCheckBox.Value = true;
            app.tgt_val = uieditfield(app.ExitTab_2, 'numeric');
            app.tgt_val.Limits = [0 Inf];
            app.tgt_val.ValueDisplayFormat = '%.0f$';
            app.tgt_val.Value = 5000;
            app.TakeprofitCheckBox = uicheckbox(app.ExitTab_2);
            app.TakeprofitCheckBox.Text = 'Take profit';
            app.TakeprofitCheckBox.Value = true;
            app.TrailingstopCheckBox = uicheckbox(app.ExitTab_2);
            app.TrailingstopCheckBox.Text = 'Trailing stop';
            app.TriggerEditFieldLabel = uilabel(app.ExitTab_2);
            app.TriggerEditFieldLabel.Text = 'Trigger';
            app.trail_trigg_val = uieditfield(app.ExitTab_2, 'numeric');
            app.trail_trigg_val.ValueDisplayFormat = '%.0f$';
            app.trail_trigg_val.Value = 2500;
            app.DistanceSpinnerLabel = uilabel(app.ExitTab_2);
            app.DistanceSpinnerLabel.Text = 'Distance';
            app.trail_dist_val = uispinner(app.ExitTab_2);
            app.trail_dist_val.Limits = [0 Inf];
            app.trail_dist_val.ValueDisplayFormat = '%.0f%%';
            app.trail_dist_val.Value = 50;
            app.StrategyTab = uitab(app.TabGroup3);
            app.StrategyTab.Title = 'Strategy';
            app.StrategyTab.Tag = 'StrategySettings';
            app.NextButton_4 = uibutton(app.StrategyTab, 'push');
            app.NextButton_4.ButtonPushedFcn = createCallbackFcn(app, @NB_GoTo_AITab, true);
            app.NextButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'next.png');
            app.NextButton_4.Text = 'Next';
            app.PreviousButton_3 = uibutton(app.StrategyTab, 'push');
            app.PreviousButton_3.ButtonPushedFcn = createCallbackFcn(app, @NB_GoTo_ExitTab, true);
            app.PreviousButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'previous.png');
            app.PreviousButton_3.Text = 'Previous';
            app.contractsEditFieldLabel = uilabel(app.StrategyTab);
            app.contractsEditFieldLabel.Text = '# contracts';
            app.contractsEditField = uieditfield(app.StrategyTab, 'numeric');
            app.contractsEditField.Value = 1;
            app.SessionstarttimehhmmLabel = uilabel(app.StrategyTab);
            app.SessionstarttimehhmmLabel.Text = 'Session start time (hhmm)';
            app.SessStart = uispinner(app.StrategyTab);
            app.SessStart.Step = 100;
            app.SessStart.Limits = [0 2359];
            app.SessStart.ValueDisplayFormat = '%.0f';
            app.SessStart.Value = 1700;
            app.SessionendtimehhmmLabel = uilabel(app.StrategyTab);
            app.SessionendtimehhmmLabel.Text = 'Session end time (hhmm)';
            app.SessEnd = uispinner(app.StrategyTab);
            app.SessEnd.Step = 100;
            app.SessEnd.Limits = [0 2359];
            app.SessEnd.ValueDisplayFormat = '%.0f';
            app.SessEnd.Value = 1600;
            app.IncludeTitanExportfromCheckBox = uicheckbox(app.StrategyTab);
            app.IncludeTitanExportfromCheckBox.Text = 'Include Titan Export from';
            app.ExportfromDatePicker = uidatepicker(app.StrategyTab);
            app.ExportfromDatePicker.DisplayFormat = 'uuuu-MMM-dd';
            app.ExportfromDatePicker.Value = datetime([2008 1 1]);
            app.AITab = uitab(app.TabGroup3);
            app.AITab.Title = 'AI';
            app.AITab.Tag = 'AISettings';
            app.PreviousButton_4 = uibutton(app.AITab, 'push');
            app.PreviousButton_4.ButtonPushedFcn = createCallbackFcn(app, @NB_GoTo_StrategyTab, true);
            app.PreviousButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'previous.png');
            app.PreviousButton_4.Text = 'Previous';
            app.askToAI_onoff = uicheckbox(app.AITab);
            app.askToAI_onoff.ValueChangedFcn = createCallbackFcn(app, @askToAI_onoffValueChanged, true);
            app.askToAI_onoff.Enable = 'off';
            app.askToAI_onoff.Text = 'I want to customize the script with a request to the AI';
            app.requestToAI = uitextarea(app.AITab);
            app.requestToAI.Visible = 'off';
            app.requestToAI.Value = {'You can type here your request to AI.'; 'For example:'; '- don''t work at all on January'; '- During even days of the month I want to trade long only'; '- On Odd days of the month I want to trade short only'};
            app.GeneratecodeButton = uibutton(app.AITab, 'push');
            app.GeneratecodeButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratecodeButtonPushed, true);
            app.GeneratecodeButton.Icon = fullfile(pathToMLAPP, 'binary-icon-11871.png');
            app.GeneratecodeButton.Text = 'Generate code';
            app.AI_lock = uiimage(app.AITab);
            app.AI_lock.Enable = 'off';
            app.AI_lock.Visible = 'off';
            app.AI_lock.Tooltip = {'Please contact support to unlock this feature'};
            app.AI_lock.ImageSource = fullfile(pathToMLAPP, 'icons', 'lock.png');
            app.ExplorerTab = uitab(app.TabGroup);
            app.ExplorerTab.Title = 'Explorer';
            app.ScriptnameDropDownLabel = uilabel(app.ExplorerTab);
            app.ScriptnameDropDownLabel.Text = 'Script name';
            app.ScriptnameDropDown = uidropdown(app.ExplorerTab);
            app.ScriptnameDropDown.Items = {'_ohlcmulti5', 'CLTrendDevelop', 'Develop', 'DevelopID', 'DevelopRev', 'ESCounter', 'FXFader', 'iADXOnArray', 'Identifying markets', 'IntraToOver', 'myGapFinder', 'PatternDirectionalFast', 'PatternFast', 'PatternNeutralFast', 'ProgStopDevelop', 'PtnBaseSA', 'UA_DivergenceMACD_Spotter', 'UA_DivergenceRSI_Spotter', 'UABIASExplorer', 'UABollBandFader', 'UALevelFader', 'Unger Webinar DAX First Hour', 'Unger Webinar FIB Night', 'WeeklyBump', 'WeeklyBump2'};
            app.ScriptnameDropDown.Value = 'Develop';
            app.ITButton = uibutton(app.ExplorerTab, 'push');
            app.ITButton.ButtonPushedFcn = createCallbackFcn(app, @ITButtonPushed, true);
            app.ITButton.Icon = '800px-Flag_of_Italy.svg.png';
            app.ITButton.Text = 'IT';
            app.ENButton = uibutton(app.ExplorerTab, 'push');
            app.ENButton.ButtonPushedFcn = createCallbackFcn(app, @ENButtonPushed, true);
            app.ENButton.Icon = 'american-us-flag-icon-8324.jpg';
            app.ENButton.Text = 'EN';
            app.GetthecodeButton = uibutton(app.ExplorerTab, 'push');
            app.GetthecodeButton.ButtonPushedFcn = createCallbackFcn(app, @GetthecodeButtonPushed, true);
            app.GetthecodeButton.Icon = 'vintage-icon-png-19158.png';
            app.GetthecodeButton.Text = 'Get the code';
            app.GotothelessonLabel = uilabel(app.ExplorerTab);
            app.GotothelessonLabel.Text = 'Go to the lesson';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.codeArea = uitextarea(app.OutputTab);
            app.codeArea.Editable = 'off';
            app.codeArea.Tooltip = {''};
            app.codeArea.Value = {'your script will be printed here!'};
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.infoButton = uibutton(app.SettingsTab, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Text = 'info';
            app.Activate_Extra5engines_Button = uibutton(app.SettingsTab, 'push');
            app.Activate_Extra5engines_Button.ButtonPushedFcn = createCallbackFcn(app, @Activate_Extra5engines_ButtonPushed, true);
            app.Activate_Extra5engines_Button.Text = 'Activate';
            app.ATFextracontentsactivationstatusSwitchLabel = uilabel(app.SettingsTab);
            app.ATFextracontentsactivationstatusSwitchLabel.Text = 'ATF extra contents activation status';
            app.ATFextracontentsactivationstatusSwitch = uiswitch(app.SettingsTab, 'slider');
            app.ATFextracontentsactivationstatusSwitch.Enable = 'off';
            app.OYTextracontentsactivationstatusSwitchLabel = uilabel(app.SettingsTab);
            app.OYTextracontentsactivationstatusSwitchLabel.Text = 'OYT extra contents activation status';
            app.OYTextracontentsactivationstatusSwitch = uiswitch(app.SettingsTab, 'slider');
            app.OYTextracontentsactivationstatusSwitch.Enable = 'off';
            app.Activate_OYT_Button = uibutton(app.SettingsTab, 'push');
            app.Activate_OYT_Button.ButtonPushedFcn = createCallbackFcn(app, @Activate_OYT_ButtonPushed, true);
            app.Activate_OYT_Button.Text = 'Activate';
            app.AIcapabilitiesactivationstatusSwitchLabel = uilabel(app.SettingsTab);
            app.AIcapabilitiesactivationstatusSwitchLabel.Text = 'AI capabilities activation status';
            app.AIcapabilitiesactivationstatusSwitch = uiswitch(app.SettingsTab, 'slider');
            app.AIcapabilitiesactivationstatusSwitch.Enable = 'off';
            app.Activate_AI_capabilities_button = uibutton(app.SettingsTab, 'push');
            app.Activate_AI_capabilities_button.ButtonPushedFcn = createCallbackFcn(app, @Activate_AI_capabilities_buttonButtonPushed, true);
            app.Activate_AI_capabilities_button.Text = 'Activate';
            app.FontsizeSpinnerLabel = uilabel(app.SettingsTab);
            app.FontsizeSpinnerLabel.Text = 'Font size';
            app.FontsizeSpinner = uispinner(app.SettingsTab);
            app.FontsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @FontsizeSpinnerValueChanged, true);
            app.FontsizeSpinner.Value = 12;
            app.output_contextmenu = uicontextmenu(app.UngerAcademyComposerUIFigure);
            app.CopytoclipboardMenu = uimenu(app.output_contextmenu);
            app.CopytoclipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @CopytoclipboardMenuSelected, true);
            app.CopytoclipboardMenu.Text = 'Copy to clipboard';
            app.SaveasfileMenu = uimenu(app.output_contextmenu);
            app.SaveasfileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasfileMenuSelected, true);
            app.SaveasfileMenu.Text = 'Save as file';
            % Assign app.output_contextmenu
            app.codeArea.ContextMenu = app.output_contextmenu;
            % Show the figure after all components are created
            app.UngerAcademyComposerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CodeWriterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UngerAcademyComposerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UngerAcademyComposerUIFigure)
        end
    end
end