classdef canc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        TabGroup  matlab.ui.container.TabGroup
        Tab       matlab.ui.container.Tab
        Tab2      matlab.ui.container.Tab
        Tab3      matlab.ui.container.Tab
        Button    matlab.ui.control.Button
        Tab4      matlab.ui.container.Tab
        Tab577    matlab.ui.container.Tab
        Tab6      matlab.ui.container.Tab
        Tab7      matlab.ui.container.Tab
        Tab8      matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Tab2.HandleVisibility
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab4;
            TabGroupSelectionChanged(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            AselectedTab = app.TabGroup.SelectedTab;
            % gestione colori
            confronto = AselectedTab.Tag;
            allC = app.TabGroup.Children;
            greyy = false;
            for ii = 1:length(allC)
                if strcmp(allC(ii).Tag,confronto)
                    greyy = true;
                end
                if greyy
                    allC(ii).ForegroundColor = "k";
                else
                    allC(ii).ForegroundColor = "r";
                end
            end
            AselectedTab.ForegroundColor = "g";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab.Tag = 'primo';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab2.Tag = 'primo1';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Tab3.Tag = 'primo2';
            app.Button = uibutton(app.Tab3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.Tab4.Tag = 'primo3';
            app.Tab577 = uitab(app.TabGroup);
            app.Tab577.Title = 'Tab5';
            app.Tab577.Tag = 'primo4';
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            app.Tab6.Tag = 'primo5';
            app.Tab7 = uitab(app.TabGroup);
            app.Tab7.Title = 'Tab7';
            app.Tab7.Tag = 'primo6';
            app.Tab8 = uitab(app.TabGroup);
            app.Tab8.Title = 'Tab8';
            app.Tab8.Tag = 'primo7';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = canc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end